classdef ProjectApp1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        TabGroup                       matlab.ui.container.TabGroup
        SimulationTab                  matlab.ui.container.Tab
        WithPVpanelButton              matlab.ui.control.Button
        RunthesimulationLabel          matlab.ui.control.Label
        WithoutPVpanelButton           matlab.ui.control.Button
        SimulationstopstatusLabel      matlab.ui.control.Label
        PlottingTab                    matlab.ui.container.Tab
        ClearthegraphButton            matlab.ui.control.Button
        SelecttheDataforplottingLabel  matlab.ui.control.Label
        WithPVButton                   matlab.ui.control.Button
        WithoutPVButton                matlab.ui.control.Button
        LegendButton                   matlab.ui.control.Button
        HourlyLoadButton               matlab.ui.control.Button
        PVoutButton                    matlab.ui.control.Button
        SOCButton                      matlab.ui.control.Button
        NetLoadButton                  matlab.ui.control.Button
        UtilityPriceButton             matlab.ui.control.Button
        BillButton                     matlab.ui.control.Button
        FuzzyOutputButton              matlab.ui.control.Button
        BALANCESHEETSTab               matlab.ui.container.Tab
        WeeklyBillButton               matlab.ui.control.Button
        TotalamountsoldButton          matlab.ui.control.Button
        SavingsButton                  matlab.ui.control.Button
        BillLabel                      matlab.ui.control.Label
        AmountsoldLabel                matlab.ui.control.Label
        NetsavingsLabel                matlab.ui.control.Label
        WithPVCheckBox                 matlab.ui.control.CheckBox
        WithoutPVCheckBox              matlab.ui.control.CheckBox
    end


    properties (Access = private)
        load;
        pvout;
        soc;
        netload;
        utprice;
        bill;
        fzout;
        time;
        wksowithoutpv;
        wksowithpv;
        ldw;
        pvw;
        nvlw;
        ldwt;
        pvwt;
        nvlwt;
        lgnd;
        updt;
        wkspv;
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'Graphical results';
            grid(app.UIAxes, 'on')
            app.lgnd = {};
            app.updt = 1;
        end

        % Button pushed function: HourlyLoadButton
        function HourlyLoadButtonPushed(app, event)
            plot(app.UIAxes, app.time, app.load)
            lc = app.updt;
            app.lgnd{1,lc} = 'Hourly load';
            app.updt = lc+1;
            hold(app.UIAxes, 'on')
        end

        % Button pushed function: PVoutButton
        function PVoutButtonPushed(app, event)
            plot(app.UIAxes, app.time, app.pvout)
            lc = app.updt;
            app.lgnd{1,lc} = 'PV output';
            app.updt = lc+1;
            %legend(app.UIAxes, 'PV power')
            hold(app.UIAxes, 'on')
        end

        % Button pushed function: UtilityPriceButton
        function UtilityPriceButtonPushed(app, event)
            plot(app.UIAxes, app.time, app.utprice)
            lc = app.updt;
            app.lgnd{1,lc} = 'Utility Price';
            app.updt = lc+1;
            %legend(app.UIAxes, 'Utility Price')
            hold(app.UIAxes, 'on')
        end

        % Button pushed function: NetLoadButton
        function NetLoadButtonPushed(app, event)
            plot(app.UIAxes, app.time, app.netload)
            lc = app.updt;
            app.lgnd{1,lc} = 'Net load';
            app.updt = lc+1;
            %legend(app.UIAxes, 'Net load')
            hold(app.UIAxes, 'on')
        end

        % Button pushed function: SOCButton
        function SOCButtonPushed(app, event)
            plot(app.UIAxes, app.time, app.soc)
            lc = app.updt;
            app.lgnd{1,lc} = 'SOC';
            app.updt = lc+1;
            %legend(app.UIAxes, 'SOC')
            hold(app.UIAxes, 'on')
        end

        % Button pushed function: FuzzyOutputButton
        function FuzzyOutputButtonPushed(app, event)
            plot(app.UIAxes, app.time, app.fzout)
            lc = app.updt;
            app.lgnd{1,lc} = 'fuzzy output';
            app.updt = lc+1;
            %legend(app.UIAxes, 'Fuzzy output')
            hold(app.UIAxes, 'on')
        end

        % Button pushed function: BillButton
        function BillButtonPushed(app, event)
            plot(app.UIAxes, app.time, app.bill)
            lc = app.updt;
            app.lgnd{1,lc} = 'The Bill';
            app.updt = lc+1;
            %legend(app.UIAxes, 'The bill')
            hold(app.UIAxes, 'on')
        end

        % Button pushed function: WithPVpanelButton
        function WithPVpanelButtonPushed(app, event)
            %title(app.UIAxes, 'Simulation Running.....')
            app.wksowithpv = sim('ModelWithPV.slx','ReturnWorkspaceOutputs','on');
            app.time = app.wksowithpv.tout'*100;
            app.ldw = app.wksowithpv.load.Data;
            app.pvw = app.wksowithpv.pv_power.Data;
            
            if max(app.ldw) > max(app.pvw)
                app.nvlw = max(app.ldw);
            else
                app.nvlw = max(app.pvw);
            end
            app.SimulationstopstatusLabel.Text = 'Simulation with PV ended';
        end

        % Button pushed function: ClearthegraphButton
        function ClearthegraphButtonPushed(app, event)
            app.UIAxes.cla;
            legend(app.UIAxes, 'off')
            app.lgnd = {};
            app.updt = 1;
            %grid(app.UIAxes, 'on')
        end

        % Button pushed function: WithoutPVpanelButton
        function WithoutPVpanelButtonPushed(app, event)
            app.wksowithoutpv = sim('ModelWithoutPV.slx','ReturnWorkspaceOutputs','on');
            app.time = app.wksowithoutpv.tout*100';
            app.ldwt = app.wksowithoutpv.load.Data;
            app.pvwt = app.wksowithoutpv.pv_power.Data;
            
            if max(app.ldwt) > max(app.pvwt)
                app.nvlwt = max(app.ldwt);
            else
                app.nvlwt = max(app.pvwt);
            end
            app.SimulationstopstatusLabel.Text = 'Simulation without PV ended';
        end

        % Button pushed function: WithoutPVButton
        function WithoutPVButtonPushed(app, event)
            app.SimulationstopstatusLabel.Text = 'Simulation status';
            app.pvout = app.pvwt'*100/app.nvlwt;
            app.load = app.ldwt'*100/app.nvlwt;
            app.soc = app.wksowithoutpv.soc.Data;
            app.netload = app.wksowithoutpv.net_load_out.Data'*100/app.nvlwt;
            app.utprice = app.wksowithoutpv.price_out.Data*100;
            app.bill = app.wksowithoutpv.bill.Data'*0.1/97.28;
            app.fzout = app.wksowithoutpv.fz_out.Data'*100/app.nvlwt;
            title(app.UIAxes,'Without PV working')
        end

        % Button pushed function: WithPVButton
        function WithPVButtonPushed(app, event)
            app.SimulationstopstatusLabel.Text = 'Simulation status';
            app.pvout = app.pvw'*100/app.nvlw;
            app.load = app.ldw'*100/app.nvlw;
            app.soc = app.wksowithpv.soc.Data;
            app.netload = app.wksowithpv.net_load_out.Data'*100/app.nvlw;
            app.utprice = app.wksowithpv.price_out.Data*100;
            app.bill = app.wksowithpv.bill.Data'*0.1/97.28;
            app.fzout = app.wksowithpv.fz_out.Data'*100/app.nvlw;
            title(app.UIAxes,'With PV working')
        end

        % Button pushed function: LegendButton
        function LegendButtonPushed(app, event)
            lc = app.updt-1;
            vl = app.lgnd;
            if lc == 1
                legend(app.UIAxes, vl{1,1})
            elseif lc == 2
                legend(app.UIAxes, vl{1,1},vl{1,2})
            elseif lc ==3
                legend(app.UIAxes, vl{1,1}, vl{1,2}, vl{1,3})
            elseif lc == 4
                legend(app.UIAxes, vl{1,1}, vl{1,2}, vl{1,3}, vl{1,4})
            elseif lc == 5
                legend(app.UIAxes, vl{1,1}, vl{1,2}, vl{1,3}, vl{1,4}, vl{1,5})
            elseif lc == 6
                legend(app.UIAxes, vl{1,1}, vl{1,2}, vl{1,3}, vl{1,4}, vl{1,5}, vl{1,6})
            elseif lc == 7
                legend(app.UIAxes, vl{1,1}, vl{1,2}, vl{1,3}, vl{1,4}, vl{1,5}, vl{1,6}, vl{1,7})
            else
                disp('excess plots')
            end
            
        end

        % Button pushed function: WeeklyBillButton
        function WeeklyBillButtonPushed(app, event)
            mybill = sum(app.wkspv.bill);
            app.BillLabel.Text = num2str(mybill);
        end

        % Value changed function: WithPVCheckBox
        function WithPVCheckBoxValueChanged(app, event)
            chbx = app.WithPVCheckBox.Value;
            if chbx
                app.wkspv = app.wksowithpv;
                app.WithoutPVCheckBox.Value = 0;
            end
            
        end

        % Value changed function: WithoutPVCheckBox
        function WithoutPVCheckBoxValueChanged(app, event)
            chbx = app.WithoutPVCheckBox.Value;
            if chbx
                app.wkspv = app.wksowithoutpv;
                app.WithPVCheckBox.Value = 0;
            end
            
        end

        % Button pushed function: TotalamountsoldButton
        function TotalamountsoldButtonPushed(app, event)
            gain = sum(app.wkspv.gains);
            app.AmountsoldLabel.Text =num2str(gain);
        end

        % Button pushed function: SavingsButton
        function SavingsButtonPushed(app, event)
            g = sum(app.wkspv.gains);
            b = sum(app.wkspv.bill);
            net = g-b;
            if net > 0
                % sold more than consumed
                strv = strcat('sold :',num2str(net));
                app.NetsavingsLabel.Text = strv;
            else
                nnet = abs(net);
                strv = strcat('net consumption :',num2str(nnet));
                app.NetsavingsLabel.Text = strv;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'time in hours')
            ylabel(app.UIAxes, 'Percentage')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SimulationTab = uitab(app.TabGroup);
            app.SimulationTab.Title = 'Simulation';
            app.WithPVpanelButton = uibutton(app.SimulationTab, 'push');
            app.WithPVpanelButton.ButtonPushedFcn = createCallbackFcn(app, @WithPVpanelButtonPushed, true);
            app.WithPVpanelButton.Text = 'With PV panel';
            app.RunthesimulationLabel = uilabel(app.SimulationTab);
            app.RunthesimulationLabel.Text = 'Run the simulation';
            app.WithoutPVpanelButton = uibutton(app.SimulationTab, 'push');
            app.WithoutPVpanelButton.ButtonPushedFcn = createCallbackFcn(app, @WithoutPVpanelButtonPushed, true);
            app.WithoutPVpanelButton.Text = 'Without PV panel';
            app.SimulationstopstatusLabel = uilabel(app.SimulationTab);
            app.SimulationstopstatusLabel.Text = 'Simulation stop status';
            app.PlottingTab = uitab(app.TabGroup);
            app.PlottingTab.Title = 'Plotting';
            app.ClearthegraphButton = uibutton(app.PlottingTab, 'push');
            app.ClearthegraphButton.ButtonPushedFcn = createCallbackFcn(app, @ClearthegraphButtonPushed, true);
            app.ClearthegraphButton.Text = 'Clear the graph';
            app.SelecttheDataforplottingLabel = uilabel(app.PlottingTab);
            app.SelecttheDataforplottingLabel.Text = 'Select the Data for plotting';
            app.WithPVButton = uibutton(app.PlottingTab, 'push');
            app.WithPVButton.ButtonPushedFcn = createCallbackFcn(app, @WithPVButtonPushed, true);
            app.WithPVButton.Text = 'With PV';
            app.WithoutPVButton = uibutton(app.PlottingTab, 'push');
            app.WithoutPVButton.ButtonPushedFcn = createCallbackFcn(app, @WithoutPVButtonPushed, true);
            app.WithoutPVButton.Text = 'Without PV';
            app.LegendButton = uibutton(app.PlottingTab, 'push');
            app.LegendButton.ButtonPushedFcn = createCallbackFcn(app, @LegendButtonPushed, true);
            app.LegendButton.Text = 'Legend';
            app.HourlyLoadButton = uibutton(app.PlottingTab, 'push');
            app.HourlyLoadButton.ButtonPushedFcn = createCallbackFcn(app, @HourlyLoadButtonPushed, true);
            app.HourlyLoadButton.Text = 'Hourly Load';
            app.PVoutButton = uibutton(app.PlottingTab, 'push');
            app.PVoutButton.ButtonPushedFcn = createCallbackFcn(app, @PVoutButtonPushed, true);
            app.PVoutButton.Text = 'PV. out';
            app.SOCButton = uibutton(app.PlottingTab, 'push');
            app.SOCButton.ButtonPushedFcn = createCallbackFcn(app, @SOCButtonPushed, true);
            app.SOCButton.Text = 'SOC';
            app.NetLoadButton = uibutton(app.PlottingTab, 'push');
            app.NetLoadButton.ButtonPushedFcn = createCallbackFcn(app, @NetLoadButtonPushed, true);
            app.NetLoadButton.Text = 'Net Load';
            app.UtilityPriceButton = uibutton(app.PlottingTab, 'push');
            app.UtilityPriceButton.ButtonPushedFcn = createCallbackFcn(app, @UtilityPriceButtonPushed, true);
            app.UtilityPriceButton.Text = 'Utility Price';
            app.BillButton = uibutton(app.PlottingTab, 'push');
            app.BillButton.ButtonPushedFcn = createCallbackFcn(app, @BillButtonPushed, true);
            app.BillButton.Text = 'Bill';
            app.FuzzyOutputButton = uibutton(app.PlottingTab, 'push');
            app.FuzzyOutputButton.ButtonPushedFcn = createCallbackFcn(app, @FuzzyOutputButtonPushed, true);
            app.FuzzyOutputButton.Text = 'Fuzzy Output';
            app.BALANCESHEETSTab = uitab(app.TabGroup);
            app.BALANCESHEETSTab.Title = 'BALANCE SHEETS';
            app.WeeklyBillButton = uibutton(app.BALANCESHEETSTab, 'push');
            app.WeeklyBillButton.ButtonPushedFcn = createCallbackFcn(app, @WeeklyBillButtonPushed, true);
            app.WeeklyBillButton.Text = 'Weekly Bill';
            app.TotalamountsoldButton = uibutton(app.BALANCESHEETSTab, 'push');
            app.TotalamountsoldButton.ButtonPushedFcn = createCallbackFcn(app, @TotalamountsoldButtonPushed, true);
            app.TotalamountsoldButton.Text = 'Total amount sold';
            app.SavingsButton = uibutton(app.BALANCESHEETSTab, 'push');
            app.SavingsButton.ButtonPushedFcn = createCallbackFcn(app, @SavingsButtonPushed, true);
            app.SavingsButton.Text = 'Savings';
            app.BillLabel = uilabel(app.BALANCESHEETSTab);
            app.BillLabel.Text = 'Bill';
            app.AmountsoldLabel = uilabel(app.BALANCESHEETSTab);
            app.AmountsoldLabel.Text = 'Amount sold';
            app.NetsavingsLabel = uilabel(app.BALANCESHEETSTab);
            app.NetsavingsLabel.Text = 'Net savings';
            app.WithPVCheckBox = uicheckbox(app.BALANCESHEETSTab);
            app.WithPVCheckBox.ValueChangedFcn = createCallbackFcn(app, @WithPVCheckBoxValueChanged, true);
            app.WithPVCheckBox.Text = 'With PV';
            app.WithoutPVCheckBox = uicheckbox(app.BALANCESHEETSTab);
            app.WithoutPVCheckBox.ValueChangedFcn = createCallbackFcn(app, @WithoutPVCheckBoxValueChanged, true);
            app.WithoutPVCheckBox.Text = 'Without PV';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ProjectApp1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end